classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.StateButton
        UITable   matlab.ui.control.Table
    end

    properties (Access = private)
        DropdownItems = {'Item1', 'Item2', 'Item3'};  % Dropdown items
        Data = {'medium','Item1',[];1,'Item2',[];'Subtrate','Item3',[]}
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = app.Data;
            app.UITable.ColumnEditable = [false, true, true];
            app.UITable.ColumnFormat = {'numeric', app.DropdownItems, 'numeric'};
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            newRow = {length(app.UITable.Data)-1, 'Item1', []};  % Initial values for the new row
            last_array={'Subtrate','Item3',[]};
            app.UITable.Data = [app.UITable.Data(1:end-1,:); newRow;last_array];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end