classdef optics_constant_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        typeofdopingButtonGroup         matlab.ui.container.ButtonGroup
        pButton_2                       matlab.ui.control.RadioButton
        nButton                         matlab.ui.control.RadioButton
        dopingconcentraioncm3EditField  matlab.ui.control.NumericEditField
        dopingconcentraioncm3EditFieldLabel  matlab.ui.control.Label
        deleteButton                    matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        addButton                       matlab.ui.control.StateButton
        downloadButton                  matlab.ui.control.StateButton
        plotButton                      matlab.ui.control.StateButton
        AngleofincidencedegreeEditField  matlab.ui.control.EditField
        AngleofincidencedegreeEditFieldLabel  matlab.ui.control.Label
        pointspacingnmEditField         matlab.ui.control.NumericEditField
        pointspacingnmEditFieldLabel    matlab.ui.control.Label
        plotendnmEditField              matlab.ui.control.NumericEditField
        plotendnmEditFieldLabel         matlab.ui.control.Label
        plotstartnmEditField            matlab.ui.control.NumericEditField
        plotstartnmLabel                matlab.ui.control.Label
        PolarizationoflightButtonGroup  matlab.ui.container.ButtonGroup
        unpolarizedButton               matlab.ui.control.RadioButton
        pButton                         matlab.ui.control.RadioButton
        sButton                         matlab.ui.control.RadioButton
        RightPanel                      matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mouse_ind;
        mouse_ind2;
        newData;
        Data = {'medium','Item1',inf;1,'Item2',[];'Subtrate','Item3',inf};
        material_list = {'Air', 'Si'};  % Dropdown items
    end
    % some functions which the app would like to use
    methods (Access = private)
        function [n,k]=doped_silicon(app,N,type,wavelength)
            T=293;%in kelvin
            v=2.99792458*10^8;%light speed vacuum/ui  m/s 
            theta=T/300;
            omega=2*pi*v/wavelength;
            if type=='n'
                A=0.0824*theta^-1.622;
                N_0=1.6*10^18*theta^0.7267;
                if N<N_0
                    B=0.4722*theta^0.0652;
                elseif N>=N_0
                    B=1.23-0.3162*theta;
                end
            elseif type=='p'
                A=0.2364*theta^-1.474;
                N_0=1.577*10^18*theta^0.46;
                if N<N_0
                    B=0.433*theta^0.2213;
                elseif N>=N_0
                    B=1.268-0.338*theta;
                end
            end
            degree_of_ionization=1-A*exp(-(B*log(N/N_0))^2);
            carrier_concentration=N*degree_of_ionization;
            if type=='n'
                mu_1=68.5;
                mu_max=1414;
                mu_2=56.1;
                C_r=9.2*10^16;
                C_s=3.41*10^20;
                alpha=0.711;
                beta=198;
                N_e=carrier_concentration;
                mu=mu_1+(mu_max-mu_1)/(1+(N_e/C_r)^alpha)-mu_2/(1+(C_s/N_e)^beta);
            elseif type=='p'
                mu_1=44.9;
                mu_max=470.5;
                mu_2=29;
                C_r=2.23*10^17;
                C_s=6.10*10^20;
                alpha=0.719;
                beta=2;
                p_c=9.23*10^16;
                N_h=carrier_concentration;
                mu=mu_1*exp(-p_c/N_h)+mu_max/(1+(N_h/C_r)^alpha)-mu_2/(1+(C_s/N_h)^beta);
            end
            e=1.602*10^-19;%-10esu
            m_0=9.109*10^-31;%KG
            epsilion0=8.854*10^-12;%ui C^2/N M^2
            dielectric_limitation=11.78;
            if type=='n'
                relative_mass=0.27*m_0;
            elseif type=='p'
                relative_mass=0.37*m_0;
            end
            omega_p=sqrt(carrier_concentration*e^2/relative_mass/epsilion0)*10^3;
            gamma=e/relative_mass/mu*10^4;
            relative_permittivity=dielectric_limitation-omega_p^2/omega/(omega+1i*gamma);
            a=abs(real(relative_permittivity));
            b=abs(imag(relative_permittivity));
            n=sqrt((a+sqrt(a^2+b^2))/2);
            k=b/2/n;
        end

        function th_list=list_snell(app,n_list,th_0)
            angles=asin(n_list(1)*sin(th_0)./ n_list);
            answer=app.is_forward_angle(n_list(1), angles(1));
            
            if answer==1
                angles(1) = pi - angles(1);
            end
            answer=app.is_forward_angle(n_list(end), angles(end));
            if answer==1
                angles(end) = pi - angles(end);
            end
            th_list=angles;
        end

        function [R,T]=coh_tmm(app,pol,n_list,d_list,th_0,lam_vac)
            th_list=app.list_snell(n_list,th_0);
            kz_list=2*pi*n_list.*cos(th_list)/lam_vac;
            delta=kz_list.*d_list;
            numlayers=length(n_list);
            t_list=zeros(numlayers,numlayers);
            r_list=zeros(numlayers,numlayers);
            for i=1:numlayers-1
                t_list(i,i+1)=app.interface_t(pol, n_list(i), n_list(i+1),th_list(i), th_list(i+1));
                r_list(i,i+1)=app.interface_r(pol, n_list(i), n_list(i+1),th_list(i), th_list(i+1));
            end
            M_list=zeros(2,2,numlayers-2);
            for i=2:numlayers-1
                m1=[exp(-1j*delta(i)),0;0,exp(1j*delta(i))];
                m2=[1,r_list(i,i+1);r_list(i,i+1),1];
                M_list(:,:,i-1)=1/t_list(i,i+1)*m1*m2;
            end
            Mtilde=[1,0;0,1];
            for i=1:numlayers-2
                Mtilde=Mtilde*M_list(:,:,i);
            end
            m3=[1,r_list(1,2);r_list(1,2),1]/t_list(1,2);
            Mtilde=m3*Mtilde;
            
            r=Mtilde(2,1)/Mtilde(1,1);
            t1=1/Mtilde(1,1);
            
            R=abs(r)^2;
            T=app.T_from_t(pol,t1,n_list(1),n_list(end),th_0,th_list(end));
        end

        function y=interface_r(app,pol,n_i,n_f,th_i,th_f)
            if pol=='s'
                y=(n_i * cos(th_i) - n_f * cos(th_f)) /(n_i * cos(th_i) + n_f * cos(th_f));
            elseif pol=='p'
                y=(n_f * cos(th_i) - n_i * cos(th_f)) /(n_f * cos(th_i) + n_i * cos(th_f));
            end
        end

        function y=interface_t(app,pol, n_i, n_f, th_i, th_f)
            if pol == 's'
                y=2 * n_i * cos(th_i) / (n_i * cos(th_i) + n_f * cos(th_f));
            elseif pol == 'p'
                y=2 * n_i * cos(th_i) / (n_f * cos(th_i) + n_i * cos(th_f));
            end
        end

        function answer=is_forward_angle(app,n,theta)
            ncostheta=n*cos(theta);
            epsilon=2.220446049250313e-16;
            if abs(ncostheta)>100*epsilon
                answer=imag(ncostheta)>0;
            else
                answer=real(ncostheta)>0;
            end
            
        end

        function T=T_from_t(app,pol,t,n_i,n_f,th_i,th_f)
            if pol=='s'
                T=abs(t^2)*(real(n_f*cos(th_f))/real(n_i*cos(th_i)));
            elseif pol=='p'
                T=abs(t^2)*(real(n_f*conj(cos(th_f)))/real(n_i*conj(cos(th_i))));
            end
        end
        
        function [R,T]=unpolarized_RT(app,n_list, d_list, th_0, lam_vac)
            [s_data1,s_data2] = app.coh_tmm('s', n_list, d_list, th_0, lam_vac);
            [p_data1,p_data2] = app.coh_tmm('p', n_list, d_list, th_0, lam_vac);
            R = (s_data1+ p_data1) / 2;
            T = (s_data2 + p_data2) / 2;
            % y=[R,T];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.UITable.Data = app.Data;
        app.UITable.ColumnEditable = [false, true, true];
        app.UITable.ColumnFormat = {'numeric', app.material_list, 'numeric'};
        end

        % Value changed function: plotButton
        function plotButtonValueChanged(app, event)
            N=app.dopingconcentraioncm3EditField.Value;
            wavelength_all=app.plotstartnmEditField.Value:app.pointspacingnmEditField.Value:app.plotendnmEditField.Value;
            R_T=zeros(length(wavelength_all),2);
            theta=str2double(app.AngleofincidencedegreeEditField.Value);
            d_list = cellfun(@(x) str2double(x),app.UITable.Data(:,3));
            d_list=d_list';
            if app.unpolarizedButton.Value==1 %unpolarized is not working in this script
                pol='u';
            elseif app.pButton.Value==1
                pol='p';
            elseif app.sButton.Value==1
                pol='s';
            end
            if app.pButton_2.Value==1
                type='p';
            elseif app.nButton.Value==1
                type='n';
            end
            for j=1:length(wavelength_all)
                [n,k]=app.doped_silicon(N,type,wavelength_all(j)*10^-9);
                n_list=[1,n+1i*k,1];
                if pol=='u'
                    [R_T(j,1),R_T(j,2)]=app.unpolarized_RT(n_list, d_list, theta*pi/180, wavelength_all(j));
                else
                    [R_T(j,1),R_T(j,2)]=app.coh_tmm(pol,n_list, d_list, theta*pi/180, wavelength_all(j));
                end
            end
            plot(app.UIAxes,wavelength_all,R_T(:,1),'Color','black');
            plot(app.UIAxes2,wavelength_all,R_T(:,2),'Color','black');
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)

        end

        % Value changed function: addButton
        function addButtonValueChanged(app, event)
            newRow = {length(app.UITable.Data)-1, 'Item1', []};  % Initial values for the new row
            last_array={'Subtrate','Item3',inf};
            app.UITable.Data = [app.UITable.Data(1:end-1,:); newRow;last_array];
            % newData1 = app.t;
            % set(app.UITable,'Data',newData1);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.newData = event.NewData;
            app.UITable.Data(app.mouse_ind,app.mouse_ind2)=cellstr(num2str(app.newData)); 
            app.Data=app.UITable.Data;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.mouse_ind = indices(1);  
            app.mouse_ind2 = indices(2);
        end

        % Value changed function: deleteButton
        function deleteButtonValueChanged(app, event)
            app.UITable.Data(app.mouse_ind,:) =[];
            app.Data=app.UITable.Data;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged2(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {709, 709};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {343, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PolarizationoflightButtonGroup = uibuttongroup(app.LeftPanel);
            app.PolarizationoflightButtonGroup.Title = 'Polarization of light';
            app.sButton = uiradiobutton(app.PolarizationoflightButtonGroup);
            app.sButton.Text = 's';
            app.sButton.Value = true;
            app.pButton = uiradiobutton(app.PolarizationoflightButtonGroup);
            app.pButton.Text = 'p';
            app.unpolarizedButton = uiradiobutton(app.PolarizationoflightButtonGroup);
            app.unpolarizedButton.Text = 'unpolarized';
            app.plotstartnmLabel = uilabel(app.LeftPanel);
            app.plotstartnmLabel.Text = 'plot start /nm';
            app.plotstartnmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.plotendnmEditFieldLabel = uilabel(app.LeftPanel);
            app.plotendnmEditFieldLabel.Text = 'plot end /nm';
            app.plotendnmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pointspacingnmEditFieldLabel = uilabel(app.LeftPanel);
            app.pointspacingnmEditFieldLabel.Text = ' point spacing /nm';
            app.pointspacingnmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngleofincidencedegreeEditFieldLabel = uilabel(app.LeftPanel);
            app.AngleofincidencedegreeEditFieldLabel.Text = 'Angle of incidence /degree';
            app.AngleofincidencedegreeEditField = uieditfield(app.LeftPanel, 'text');
            app.AngleofincidencedegreeEditField.Value = '45';
            app.plotButton = uibutton(app.LeftPanel, 'state');
            app.plotButton.ValueChangedFcn = createCallbackFcn(app, @plotButtonValueChanged, true);
            app.plotButton.Text = 'plot';
            app.downloadButton = uibutton(app.LeftPanel, 'state');
            app.downloadButton.Text = 'download';
            app.addButton = uibutton(app.LeftPanel, 'state');
            app.addButton.ValueChangedFcn = createCallbackFcn(app, @addButtonValueChanged, true);
            app.addButton.Text = 'add';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Layer Number'; 'Material'; 'Thickness(nm)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged2, true);
            app.deleteButton = uibutton(app.LeftPanel, 'state');
            app.deleteButton.ValueChangedFcn = createCallbackFcn(app, @deleteButtonValueChanged, true);
            app.deleteButton.Text = 'delete';
            app.dopingconcentraioncm3EditFieldLabel = uilabel(app.LeftPanel);
            app.dopingconcentraioncm3EditFieldLabel.Text = 'doping concentraion/cm-3';
            app.dopingconcentraioncm3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.typeofdopingButtonGroup = uibuttongroup(app.LeftPanel);
            app.typeofdopingButtonGroup.Title = 'type of doping ';
            app.nButton = uiradiobutton(app.typeofdopingButtonGroup);
            app.nButton.Text = 'n';
            app.nButton.Value = true;
            app.pButton_2 = uiradiobutton(app.typeofdopingButtonGroup);
            app.pButton_2.Text = 'p';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Wavelength(nm)')
            ylabel(app.UIAxes, 'Reflectance')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2, 'Wavelength(nm)')
            ylabel(app.UIAxes2, 'Transmittance')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optics_constant_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end